classdef Convolving < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SamplingRatesamplesecondEditFieldLabel  matlab.ui.control.Label
        SamplingRatesamplesecondEditField  matlab.ui.control.NumericEditField
        Function1Panel                  matlab.ui.container.Panel
        function1DropDownLabel          matlab.ui.control.Label
        function1DropDown               matlab.ui.control.DropDown
        WindowPanel                     matlab.ui.container.Panel
        AmplitudeEditFieldLabel_2       matlab.ui.control.Label
        WAmplitudeEditField             matlab.ui.control.NumericEditField
        ExponentialPanel                matlab.ui.container.Panel
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        SAmplitudeEditField1            matlab.ui.control.NumericEditField
        powerEditFieldLabel             matlab.ui.control.Label
        powerEditField                  matlab.ui.control.NumericEditField
        SinusoidPanel                   matlab.ui.container.Panel
        angularfrequencyEditFieldLabel  matlab.ui.control.Label
        angularfrequencyEditField       matlab.ui.control.NumericEditField
        amplitudeLabel                  matlab.ui.control.Label
        amplitudeEditField              matlab.ui.control.NumericEditField
        PhaseSliderLabel                matlab.ui.control.Label
        PhaseSlider                     matlab.ui.control.Slider
        SignalwidthSpinnerLabel         matlab.ui.control.Label
        Signal_1widthSpinner            matlab.ui.control.Spinner
        Label                           matlab.ui.control.Label
        plotButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Function2Panel                  matlab.ui.container.Panel
        function2DropDownLabel          matlab.ui.control.Label
        function2DropDown               matlab.ui.control.DropDown
        WindowPanel_2                   matlab.ui.container.Panel
        AmplitudeEditFieldLabel_3       matlab.ui.control.Label
        WAmplitudeEditField_2           matlab.ui.control.NumericEditField
        ExponentialPanel_2              matlab.ui.container.Panel
        AmplitudeEditFieldLabel_4       matlab.ui.control.Label
        SAmplitudeEditField1_2          matlab.ui.control.NumericEditField
        powerEditField_2Label           matlab.ui.control.Label
        powerEditField_2                matlab.ui.control.NumericEditField
        SinusoidPanel_2                 matlab.ui.container.Panel
        angularfrequencyEditField_2Label  matlab.ui.control.Label
        angularfrequencyEditField_2     matlab.ui.control.NumericEditField
        amplitudeEditField_2Label       matlab.ui.control.Label
        amplitudeEditField_2            matlab.ui.control.NumericEditField
        PhaseSlider_2Label              matlab.ui.control.Label
        PhaseSlider_2                   matlab.ui.control.Slider
        SignalwidthSpinnerLabel_2       matlab.ui.control.Label
        Signal_1widthSpinner_2          matlab.ui.control.Spinner
        Label_3                         matlab.ui.control.Label
        plotButton_2                    matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        ConvolutionButton               matlab.ui.control.Button
    end


    properties (Access = public)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        sinusoid1 
        sinusoid2
        %an array for sinusoid information
        %including 1.amplitude 2.omega 3.phase 
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        winWidth1
        winWidth2%window width
        sampRate %for sampling rate
        x1 % Description
        x2
        y1 % Description
        y2
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        expo1
        expo2% an array for exponential data
        %1.Constant multiplied by exponent
        %2.coefficient of power of t 
        % Description
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        winAmp1 % Description
        winAmp2
    end


    methods (Access = public)
    
        function [x, y] = myPlotSin(app,Axes, sinArr,width)
            x = 0:app.sampRate:width-1;
            y  = sinArr(1)*sin(sinArr(2)*x+sinArr(3)*pi);
            stem(Axes, x,y);
        end        
    end

    methods (Access = private)
    
        function res = funcType(app, dropDown)
            val = dropDown;
            disp(val);
            if isequal(val, 'Sinusoid')
                res = 1;
            elseif isequal(val, 'Exponential')
                res = 2;
            elseif isequal(val,'Window')
                res = 3;
            end
        end
        
        function [x,y] = myPlotExp(app,Axes,expoArray, width)
            %plots exponential functions
            x = 0:app.sampRate:width-1;
            y = expoArray(1)*exp(expoArray(2)*x);
            stem(Axes,x, y);
        end
        
        function [x, y] = myPlotWin(app,Axes,amp,width)
            x = 0:app.sampRate:width-1;
            y = 0 .* x + amp;
            stem(Axes, x,y);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.x1 = 0;
            app.x2 = 0;
            app.y1 = 0;
            app.y2 = 0;
            app.sampRate = 0;
            app.winWidth1 = 0;
            app.winWidth2 = 0;
            %sinusoid initialization
            app.sinusoid1 = zeros(1,3);
            app.sinusoid2 = zeros(1,3);
            %exponential initialization
            app.expo1 = zeros(1,2);
            app.expo2 = zeros(1,2);
            app.SinusoidPanel.Visible = 'on';
            app.SinusoidPanel_2.Visible = 'on';
            app.ExponentialPanel.Visible = 'off';
            app.ExponentialPanel_2.Visible = 'off';
            app.WindowPanel.Visible = 'off';
            app.WindowPanel.Visible = 'off';
            
        end

        % Value changed function: angularfrequencyEditField
        function angularfrequencyEditFieldValueChanged(app, event)
            value = app.angularfrequencyEditField.Value;
            app.sinusoid1(2) = value;
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            plotType = funcType(app, app.function1DropDown.Value);
        switch plotType
            case 1
                [app.x1, app.y1] = myPlotSin(app, app.UIAxes,app.sinusoid1, app.winWidth1);
            case 2
                [app.x1, app.y1] = myPlotExp(app,app.UIAxes, app.expo1, app.winWidth1);
            case 3
                [app.x1, app.y1] = myPlotWin(app,app.UIAxes,app.winAmp1,app.winWidth1);
        end
        end

        % Value changed function: SamplingRatesamplesecondEditField
        function SamplingRatesamplesecondEditFieldValueChanged(app, event)
            value = app.SamplingRatesamplesecondEditField.Value;
            app.sampRate = value;
        end

        % Value changed function: Signal_1widthSpinner
        function Signal_1widthSpinnerValueChanged(app, event)
            value = app.Signal_1widthSpinner.Value;
            app.winWidth1 = value;
        end

        % Value changed function: amplitudeEditField
        function amplitudeEditFieldValueChanged(app, event)
            value = app.amplitudeEditField.Value;
            app.sinusoid1(1) = value;
        end

        % Value changing function: PhaseSlider
        function PhaseSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label.Text = num2str(changingValue);
        end

        % Value changed function: PhaseSlider
        function PhaseSliderValueChanged(app, event)
            value = app.PhaseSlider.Value;
            app.sinusoid1(3) = value;
        end

        % Value changed function: SAmplitudeEditField1
        function SAmplitudeEditField1ValueChanged(app, event)
            value = app.SAmplitudeEditField1.Value;
            app.expo1(1) = value;
        end

        % Value changed function: powerEditField
        function powerEditFieldValueChanged(app, event)
            value = app.powerEditField.Value;
            app.expo1(2) = value;
        end

        % Value changed function: function1DropDown
        function function1DropDownValueChanged(app, event)
            value = app.function1DropDown.Value;
            type = funcType(app, value);
            switch type
                case 1
                    app.ExponentialPanel.Visible = 'off';
                    app.SinusoidPanel.Visible='on';
                    app.WindowPanel.Visible = 'off';
                case 2
                    app.ExponentialPanel.Visible = 'on';
                    app.SinusoidPanel.Visible = 'off';
                    app.WindowPanel.Visible = 'off';
                case 3
                    app.WindowPanel.Visible = 'on';
                    app.SinusoidPanel.Visible = 'off';
                    app.ExponentialPanel.Visible = 'off';
            end
        end

        % Callback function
        function WAmplitudeEditField1ValueChanging(app, event)
            changingValue = event.Value;
            app.winAmp1 = changingValue;
        end

        % Value changed function: WAmplitudeEditField
        function WAmplitudeEditFieldValueChanged(app, event)
            value = app.WAmplitudeEditField.Value;
            app.winAmp1 = value;
        end

        % Value changed function: WAmplitudeEditField_2
        function WAmplitudeEditField_2ValueChanged(app, event)
            value = app.WAmplitudeEditField_2.Value;
            app.winAmp2 = value;
        end

        % Value changed function: amplitudeEditField_2
        function amplitudeEditField_2ValueChanged(app, event)
            value = app.amplitudeEditField_2.Value;
            app.sinusoid2(1) = value;
        end

        % Value changed function: angularfrequencyEditField_2
        function angularfrequencyEditField_2ValueChanged(app, event)
            value = app.angularfrequencyEditField_2.Value;
            app.sinusoid2(2) = value;
        end

        % Value changed function: PhaseSlider_2
        function PhaseSlider_2ValueChanged(app, event)
            value = app.PhaseSlider_2.Value;
            app.sinusoid2(3) = value;
        end

        % Value changed function: Signal_1widthSpinner_2
        function Signal_1widthSpinner_2ValueChanged(app, event)
            value = app.Signal_1widthSpinner_2.Value;
            app.winWidth2 = value;
        end

        % Value changing function: PhaseSlider_2
        function PhaseSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_3.Text = num2str(changingValue);
        end

        % Value changed function: SAmplitudeEditField1_2
        function SAmplitudeEditField1_2ValueChanged(app, event)
            value = app.SAmplitudeEditField1_2.Value;
            app.expo2(1) = value;
        end

        % Value changed function: powerEditField_2
        function powerEditField_2ValueChanged(app, event)
            value = app.powerEditField_2.Value;
            app.expo2(2) = value;
        end

        % Value changed function: function2DropDown
        function function2DropDownValueChanged(app, event)
            value = app.function2DropDown.Value;
            type = funcType(app, value);
            switch type
                case 1
                    app.SinusoidPanel_2.Visible = 'on';
                    app.ExponentialPanel_2.Visible = 'off';
                    app.WindowPanel_2.Visible = 'off';
                case 2
                    app.SinusoidPanel_2.Visible = 'off';
                    app.ExponentialPanel_2.Visible = 'on';
                    app.WindowPanel_2.Visible = 'off';
                case 3
                    app.SinusoidPanel_2.Visible = 'off';
                    app.ExponentialPanel_2.Visible = 'off';
                    app.WindowPanel_2.Visible = 'on';
            end
                    
        end

        % Button pushed function: plotButton_2
        function plotButton_2Pushed(app, event)
            plotType = funcType(app, app.function2DropDown.Value);
            switch plotType
                case 1
                   [app.x2, app.y2] = myPlotSin (app, app.UIAxes2,app.sinusoid2, app.winWidth2);
                case 2
                    [app.x2, app.y2] = myPlotExp(app, app.UIAxes2, app.expo2, app.winWidth2);
                case 3
                    [app.x2, app.y2] = myPlotWin(app, app.UIAxes2, app.winAmp2, app.winWidth2);
            end
        end

        % Button pushed function: ConvolutionButton
        function ConvolutionButtonPushed(app, event)
            convRes(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SamplingRatesamplesecondEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRatesamplesecondEditFieldLabel.Text = 'Sampling Rate (sample/second)';
            app.SamplingRatesamplesecondEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRatesamplesecondEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRatesamplesecondEditFieldValueChanged, true);
            app.Function1Panel = uipanel(app.UIFigure);
            app.Function1Panel.Title = 'Function 1';
            app.function1DropDownLabel = uilabel(app.Function1Panel);
            app.function1DropDownLabel.Text = 'function 1';
            app.function1DropDown = uidropdown(app.Function1Panel);
            app.function1DropDown.Items = {'Sinusoid', 'Exponential', 'Window'};
            app.function1DropDown.ValueChangedFcn = createCallbackFcn(app, @function1DropDownValueChanged, true);
            app.function1DropDown.Value = 'Sinusoid';
            app.WindowPanel = uipanel(app.Function1Panel);
            app.WindowPanel.Title = 'Window';
            app.AmplitudeEditFieldLabel_2 = uilabel(app.WindowPanel);
            app.AmplitudeEditFieldLabel_2.Text = 'Amplitude';
            app.WAmplitudeEditField = uieditfield(app.WindowPanel, 'numeric');
            app.WAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @WAmplitudeEditFieldValueChanged, true);
            app.ExponentialPanel = uipanel(app.Function1Panel);
            app.ExponentialPanel.Title = 'Exponential';
            app.AmplitudeEditFieldLabel = uilabel(app.ExponentialPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.SAmplitudeEditField1 = uieditfield(app.ExponentialPanel, 'numeric');
            app.SAmplitudeEditField1.ValueChangedFcn = createCallbackFcn(app, @SAmplitudeEditField1ValueChanged, true);
            app.powerEditFieldLabel = uilabel(app.ExponentialPanel);
            app.powerEditFieldLabel.Text = 'power';
            app.powerEditField = uieditfield(app.ExponentialPanel, 'numeric');
            app.powerEditField.ValueChangedFcn = createCallbackFcn(app, @powerEditFieldValueChanged, true);
            app.SinusoidPanel = uipanel(app.Function1Panel);
            app.SinusoidPanel.Title = 'Sinusoid';
            app.angularfrequencyEditFieldLabel = uilabel(app.SinusoidPanel);
            app.angularfrequencyEditFieldLabel.Text = 'angular frequency';
            app.angularfrequencyEditField = uieditfield(app.SinusoidPanel, 'numeric');
            app.angularfrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @angularfrequencyEditFieldValueChanged, true);
            app.amplitudeLabel = uilabel(app.SinusoidPanel);
            app.amplitudeLabel.Text = 'amplitude';
            app.amplitudeEditField = uieditfield(app.SinusoidPanel, 'numeric');
            app.amplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @amplitudeEditFieldValueChanged, true);
            app.PhaseSliderLabel = uilabel(app.SinusoidPanel);
            app.PhaseSliderLabel.Text = 'Phase';
            app.PhaseSlider = uislider(app.SinusoidPanel);
            app.PhaseSlider.Limits = [0 2];
            app.PhaseSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.PhaseSlider.ValueChangedFcn = createCallbackFcn(app, @PhaseSliderValueChanged, true);
            app.PhaseSlider.ValueChangingFcn = createCallbackFcn(app, @PhaseSliderValueChanging, true);
            app.SignalwidthSpinnerLabel = uilabel(app.Function1Panel);
            app.SignalwidthSpinnerLabel.Text = 'Signal width';
            app.Signal_1widthSpinner = uispinner(app.Function1Panel);
            app.Signal_1widthSpinner.ValueChangedFcn = createCallbackFcn(app, @Signal_1widthSpinnerValueChanged, true);
            app.Label = uilabel(app.Function1Panel);
            app.Label.Text = '';
            app.plotButton = uibutton(app.Function1Panel, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.UIAxes = uiaxes(app.Function1Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Function2Panel = uipanel(app.UIFigure);
            app.Function2Panel.Title = 'Function 2';
            app.function2DropDownLabel = uilabel(app.Function2Panel);
            app.function2DropDownLabel.Text = 'function 2';
            app.function2DropDown = uidropdown(app.Function2Panel);
            app.function2DropDown.Items = {'Sinusoid', 'Exponential', 'Window'};
            app.function2DropDown.ValueChangedFcn = createCallbackFcn(app, @function2DropDownValueChanged, true);
            app.function2DropDown.Value = 'Sinusoid';
            app.WindowPanel_2 = uipanel(app.Function2Panel);
            app.WindowPanel_2.Title = 'Window';
            app.AmplitudeEditFieldLabel_3 = uilabel(app.WindowPanel_2);
            app.AmplitudeEditFieldLabel_3.Text = 'Amplitude';
            app.WAmplitudeEditField_2 = uieditfield(app.WindowPanel_2, 'numeric');
            app.WAmplitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @WAmplitudeEditField_2ValueChanged, true);
            app.ExponentialPanel_2 = uipanel(app.Function2Panel);
            app.ExponentialPanel_2.Title = 'Exponential';
            app.AmplitudeEditFieldLabel_4 = uilabel(app.ExponentialPanel_2);
            app.AmplitudeEditFieldLabel_4.Text = 'Amplitude';
            app.SAmplitudeEditField1_2 = uieditfield(app.ExponentialPanel_2, 'numeric');
            app.SAmplitudeEditField1_2.ValueChangedFcn = createCallbackFcn(app, @SAmplitudeEditField1_2ValueChanged, true);
            app.powerEditField_2Label = uilabel(app.ExponentialPanel_2);
            app.powerEditField_2Label.Text = 'power';
            app.powerEditField_2 = uieditfield(app.ExponentialPanel_2, 'numeric');
            app.powerEditField_2.ValueChangedFcn = createCallbackFcn(app, @powerEditField_2ValueChanged, true);
            app.SinusoidPanel_2 = uipanel(app.Function2Panel);
            app.SinusoidPanel_2.Title = 'Sinusoid';
            app.angularfrequencyEditField_2Label = uilabel(app.SinusoidPanel_2);
            app.angularfrequencyEditField_2Label.Text = 'angular frequency';
            app.angularfrequencyEditField_2 = uieditfield(app.SinusoidPanel_2, 'numeric');
            app.angularfrequencyEditField_2.ValueChangedFcn = createCallbackFcn(app, @angularfrequencyEditField_2ValueChanged, true);
            app.amplitudeEditField_2Label = uilabel(app.SinusoidPanel_2);
            app.amplitudeEditField_2Label.Text = 'amplitude';
            app.amplitudeEditField_2 = uieditfield(app.SinusoidPanel_2, 'numeric');
            app.amplitudeEditField_2.ValueChangedFcn = createCallbackFcn(app, @amplitudeEditField_2ValueChanged, true);
            app.PhaseSlider_2Label = uilabel(app.SinusoidPanel_2);
            app.PhaseSlider_2Label.Text = 'Phase';
            app.PhaseSlider_2 = uislider(app.SinusoidPanel_2);
            app.PhaseSlider_2.Limits = [0 2];
            app.PhaseSlider_2.MajorTicks = [0 0.5 1 1.5 2];
            app.PhaseSlider_2.ValueChangedFcn = createCallbackFcn(app, @PhaseSlider_2ValueChanged, true);
            app.PhaseSlider_2.ValueChangingFcn = createCallbackFcn(app, @PhaseSlider_2ValueChanging, true);
            app.SignalwidthSpinnerLabel_2 = uilabel(app.Function2Panel);
            app.SignalwidthSpinnerLabel_2.Text = 'Signal width';
            app.Signal_1widthSpinner_2 = uispinner(app.Function2Panel);
            app.Signal_1widthSpinner_2.ValueChangedFcn = createCallbackFcn(app, @Signal_1widthSpinner_2ValueChanged, true);
            app.Label_3 = uilabel(app.Function2Panel);
            app.Label_3.Text = '';
            app.plotButton_2 = uibutton(app.Function2Panel, 'push');
            app.plotButton_2.ButtonPushedFcn = createCallbackFcn(app, @plotButton_2Pushed, true);
            app.plotButton_2.Text = 'plot';
            app.UIAxes2 = uiaxes(app.Function2Panel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ConvolutionButton = uibutton(app.UIFigure, 'push');
            app.ConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolutionButtonPushed, true);
            app.ConvolutionButton.Text = 'Convolution';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Convolving

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end