classdef analyzer01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InputButtonGroup                matlab.ui.container.ButtonGroup
        audiofileButton                 matlab.ui.control.RadioButton
        BuiltinfuncitonsButton          matlab.ui.control.RadioButton
        BrowseButton                    matlab.ui.control.Button
        BuiltDropDown                   matlab.ui.control.DropDown
        TabGroup                        matlab.ui.container.TabGroup
        TimeplotTab                     matlab.ui.container.Tab
        AxesTime                        matlab.ui.control.UIAxes
        FrequencyplotTab                matlab.ui.container.Tab
        AxesFreq                        matlab.ui.control.UIAxes
        ComparisonTab                   matlab.ui.container.Tab
        CompareButton                   matlab.ui.control.Button
        Window1Panel                    matlab.ui.container.Panel
        Window1TypeDropDownLabel        matlab.ui.control.Label
        Window1TypeDropDown             matlab.ui.control.DropDown
        Window1lengthEditFieldLabel     matlab.ui.control.Label
        Window1lengthEditField          matlab.ui.control.NumericEditField
        NfftDropDown_2Label             matlab.ui.control.Label
        NfftDropDown_2                  matlab.ui.control.DropDown
        Window2Panel                    matlab.ui.container.Panel
        Window2TypeDropDownLabel        matlab.ui.control.Label
        Window2TypeDropDown             matlab.ui.control.DropDown
        Window2lengthEditFieldLabel     matlab.ui.control.Label
        Window2lengthEditField          matlab.ui.control.NumericEditField
        NfftDropDown_3Label             matlab.ui.control.Label
        NfftDropDown_3                  matlab.ui.control.DropDown
        CalculationTab                  matlab.ui.container.Tab
        AxesFreq_2                      matlab.ui.control.UIAxes
        EndFrequencyHzEditFieldLabel    matlab.ui.control.Label
        EndFrequencyHzEditField         matlab.ui.control.NumericEditField
        StartfrequencyHzEditFieldLabel  matlab.ui.control.Label
        StartfrequencyHzEditField       matlab.ui.control.NumericEditField
        PlotspanButton                  matlab.ui.control.Button
        RMSAveragingPanel               matlab.ui.container.Panel
        PlotButton_2                    matlab.ui.control.Button
        NblockEditFieldLabel            matlab.ui.control.Label
        NblockEditField                 matlab.ui.control.NumericEditField
        MaxPowerPanel                   matlab.ui.container.Panel
        CalculateButton                 matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        DisplayoptionsTab               matlab.ui.container.Tab
        FrequecyplottypeButtonGroup     matlab.ui.container.ButtonGroup
        MagnitudeButton                 matlab.ui.control.RadioButton
        PhaseButton                     matlab.ui.control.RadioButton
        RealButton_2                    matlab.ui.control.RadioButton
        ImaginaryButton_2               matlab.ui.control.RadioButton
        ScaleButtonGroup                matlab.ui.container.ButtonGroup
        LinearButton                    matlab.ui.control.RadioButton
        LogarithmicButton               matlab.ui.control.RadioButton
        PlotButton                      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        NfftLabel                       matlab.ui.control.Label
        NfftDropDown                    matlab.ui.control.DropDown
    end


    properties (Access = public)
        input % stores the input audiofile
        Fs    % stores the sampling frequency of the file
        Nfft % descibes the user choosen fft points
        inFreq % the frequency domain representation
        linLog % if 0, linear, if 1, log scale (doesn't work for phase)
        mode % dsiplay mode string for magnitude, phase, real, imaginary
        res1 % stores information about the first window
        res2 %stores information about the second window
        res1F
        res2F
        
    end
    
    methods (Access = public)


        function  initFFT(app)
            app.NfftDropDown.Items(1) = {'Default'};
            app.NfftDropDown_2.Items(1) = {'Default'};
            app.NfftDropDown_3.Items(1) = {'Default'};
            for i=2:1:17        %powers of 2 unitl 64k
                app.NfftDropDown.Items(i) = {num2str(2^(i-1))};
                app.NfftDropDown_2.Items(i) = {num2str(2^(i-1))};
                app.NfftDropDown_3.Items(i) = {num2str(2^(i-1))};
            end
        end
        
        function plotTime(app)
            t = 0 : 1/app.Fs : 1/app.Fs*length(app.input); %to adjust the time axis to be in seconds
            t = t(1:length(t)-1);
            plot(app.AxesTime, t,app.input);
            
        end
        
        
        function omega = phasePlot(app)
            realFreq = real(app.inFreq);
            imFreq = imag(app.inFreq);
            omega = atan(imFreq./realFreq);
            omega = rad2deg(omega);
            %plot(app.AxesFreq, F, omega);
        end
        
        function realF = realPlot(app)
            realF = real(app.inFreq);
            %plot(app.AxesFreq, F, realF);
        end
        
        function imF = imPlot(app)
            imF = imag(app.inFreq);
            %plot(app.AxesFreq, F, imF);
        end
        
        function magF = magPlot(app)
            magF = abs(app.inFreq);
            %stem(app.AxesFreq, F, magF);
        end
        
        
        function plotFreq(app)
            F = -length(app.inFreq)/2:1:length(app.inFreq)/2 -1;
            F = F*app.Fs/app.Nfft; %f = mFs/N
            
            switch app.mode                
                case 'Phase'
                   toPlot =  phasePlot(app);
                   ylbl = 'phase (deg) ';
                case 'Magnitude'
                    toPlot = magPlot(app);
                    ylbl = 'Magnitude ';
                case 'Real'
                    toPlot = realPlot(app);
                    ylbl = 'Real';
                case 'Imaginary'
                    toPlot = imPlot(app);
                    ylbl = 'Imaginary';
            end
            if app.linLog == 1 && ~isequal(app.mode, 'Phase')
                toPlot = 20*log(toPlot);
                ylbl = [ylbl, ' Logarithmic'];
            else
                ylbl = [ylbl, ' Linear'];
                %log flag is on and the phase isn't selected
            end
            %plot(F, toPlot);
            plot(app.AxesFreq, F, toPlot);
            ylabel(app.AxesFreq, ylbl);
        end
        
        function [L1, L2, selected1, selected2, fft2, fft3] = readData(app)
            L1 = app.Window1lengthEditField.Value;
            L2 = app.Window2lengthEditField.Value;
            
            selected1 = app.Window1TypeDropDown.Value;
            selected2 = app.Window2TypeDropDown.Value;
            
            %%read the N-fft choice
            value2 = app.NfftDropDown_2.Value;
            if(isequal(value2, 'Default'))
                fft2 = -1;
            else
                fft2 = str2num(value2);
            end
            value3 = app.NfftDropDown_3.Value;
            if(isequal(value3, 'Default'))
                fft3 = -1;
            else
                fft3 = str2num(value3);
            end
        end
        
        function type = detType(app, selected)
            switch selected
                case 'Rectangular'
                    type = 0;
                case 'Triangular'
                    type = 1;
                case 'Hanning'
                    type = 2;
                case 'Hamming'
                    type = 3;
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BuiltDropDown.Enable = 'off';
            initFFT(app);
            app.Nfft = -1;
            app.linLog = 0;
            app.mode = 'Magnitude';
            app.Fs = 10;
            
            %%comparison mode initialization

        end

        % Selection changed function: InputButtonGroup
        function InputButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputButtonGroup.SelectedObject;
            %manging good selection
            if(isequal(selectedButton.Text, 'audio file'))
                app.BrowseButton.Enable = 'on';
                app.BuiltDropDown.Enable = 'off';
            else
                app.BrowseButton.Enable = 'off';
                app.BuiltDropDown.Enable = 'on';
                BuiltDropDownValueChanged(app, event);
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %set the input and sampling frequency
            [filename,path] = uigetfile('*.*');
            wholePath = [path, filename];
            [app.input, app.Fs] = audioread(wholePath);
            app.input = app.input'; %to make it a row vector
          
            %dsiplay the length of the signal
            app.Label.Text = ['The signal length is ',num2str(length(app.input))];            
        end

        % Value changed function: BuiltDropDown
        function BuiltDropDownValueChanged(app, event)
            %adjust the input signal and the sampling frequency
            value = app.BuiltDropDown.Value;
            app.Fs = 10; %this is constant [ We can change it ]
            t = 0:1/app.Fs:8*pi;
            if(isequal(value, 'Sinusoid'))
                app.input = sin(t);
            else
                app.input = ones(1,length(t));
                app.input(3*length(t)/4:length(t)) = 0; %Window
            end
            app.Label.Text = ['The signal length is ',num2str(length(app.input))];
        end

        % Value changed function: NfftDropDown
        function NfftDropDownValueChanged(app, event)
            value = app.NfftDropDown.Value;
            if(isequal(value, 'Default'))
                app.Nfft = -1; %FLAG
            else
                app.Nfft = str2num(value);
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Modify the frequency signal
            value = app.NfftDropDown.Value;
            if(isequal(value, 'Default'))
                app.Nfft = -1;
            else
                app.Nfft = str2num(value);
            end
            if(app.Nfft == -1)
                app.Nfft = 2^nextpow2(length(app.input));
                app.inFreq = my_fft2(app.input, app.Nfft);
            else
                app.inFreq = my_fft2(app.input, app.Nfft);
            end
            L = length(app.inFreq);
            fHalf = app.inFreq(1:L/2);
            sHalf = app.inFreq(L/2+1:L);
            app.inFreq = [sHalf,fHalf];
            %plot the frequency and time signals
            plotFreq(app);
            plotTime(app);
            app.Label_4.Text = ['RBW = ',num2str(app.Fs/app.Nfft)];
        end

        % Selection changed function: FrequecyplottypeButtonGroup
        function FrequecyplottypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FrequecyplottypeButtonGroup.SelectedObject;
            app.mode = selectedButton.Text; 
            if(isequal(app.mode, 'Phase'))
                app.ScaleButtonGroup.Visible = 'off';
            else
                app.ScaleButtonGroup.Visible = 'on';
            end
        end

        % Selection changed function: ScaleButtonGroup
        function ScaleButtonGroupSelectionChanged(app, event)
            selectedButton = app.ScaleButtonGroup.SelectedObject;
            if(isequal(selectedButton.Text,'Linear'))
                app.linLog = 0;
            else
                app.linLog = 1;
            end
        end

        % Button pushed function: CompareButton
        function CompareButtonPushed(app, event)
            %read data from fields
            [L1, L2, sel1, sel2,fft2, fft3] = readData(app);
            type1 = detType(app,sel1);
            type2 = detType(app,sel2);
            %generate the windows
            filt1 = generate_window(type1, L1);
            filt2 = generate_window(type2, L2);
            win1 = zeros(1,length(app.input));
            win2 = zeros(1, length(app.input));
            
            win1(1,length(win1)/2 - L1/2: length(win1)/2 + L1/2-1) = filt1;
            win2(1,length(win2)/2 - L2/2: length(win2)/2 + L2/2-1) = filt2;
            %perform multiplication
            %app.res1 = conv(app.input, filt1);
            %app.res2 = conv(app.input, filt2);
            app.res1 = win1.*app.input;
            app.res2 = win2.*app.input;
            
            %%adjust the time axis
            t1 = 0 : 1/app.Fs : 1/app.Fs*length(app.input); %to adjust the time axis to be in seconds
            t1 = t1(1:length(t1)-1);
            
            t2 = 0 : 1/app.Fs : 1/app.Fs*length(app.input); %to adjust the time axis to be in seconds
            t2 = t2(1:length(t2)-1);
            %get the fft
            if(fft2 == -1)
                fft2 = 2^nextpow2(length(app.res1));
            end
            if(fft3 == -1)
                fft3 = 2^nextpow2(length(app.res2));
            end
            app.res1F = my_fft2(app.res1, fft2);
            app.res2F = my_fft2(app.res2, fft3);
            %% plotting
            
            
            %%adjusting the frequencies axes
            Le1 = length(app.res1F);
            Le2 = length(app.res2F);
            app.res1F = [app.res1F(Le1/2+1:Le1), app.res1F(1:Le1/2)];
            app.res2F = [app.res2F(Le2/2+1:Le2), app.res2F(1:Le2/2)]; 
            %plotting mode
            switch app.mode
                case 'Phase'
                    %first
                    realFreq1 = real(app.res1F);
                    imFreq1 = imag(app.res1F);
                    omega1 = atan(imFreq1./realFreq1);
                    omega1 = rad2deg(omega);
                    toPlot1 =  omeaga1;
                    %second
                    realFreq2 = real(app.res2F);
                    imFreq2 = imag(app.res2F);
                    omega2 = atan(imFreq2./realFreq2);
                    toPlot2 = rad2deg(omega2);
                    
                    ylbl = 'Phase (deg)';
                    
                case 'Magnitude'
                    toPlot1 = abs(app.res1F);
                    toPlot2 = abs(app.res2F);
                    
                    ylbl = 'Magnitude';
                case 'Real'
                    toPlot1 = real(app.res1F);
                    toPlot2 = real(app.res2F);
                    
                    ylbl = 'Real';
                case 'Imaginary'
                    toPlot1 = imag(app.res1F);
                    toPlot2 = imag(app.res2F);
                    
                    ylbl = 'Imaginary';
            end
            if app.linLog == 1 && ~isequal(app.mode, 'Phase')
                toPlot1 = 20*log(toPlot1);
                toPlot2 = 20*log(toPlot2);
                ylbl = [ylbl, ' Logarithmic'];
                %log flag is on and the phase isn't selected
            else
                ylbl = [ylbl, ' linear'];
            end
            %plot(F, toPlot);
            F1 = -length(app.res1F)/2:1:length(app.res1F)/2 -1;
            F1 = F1*app.Fs/app.Nfft; %f = mFs/N
            
            F2 = -length(app.res2F)/2:1:length(app.res2F)/2 -1;
            F2 = F2*app.Fs/app.Nfft; %f = mFs/N
            
            %%adjust the freqeuncies axes


            figure;
            subplot(2,2,1);
            plot(t1,app.res1);
            xlabel 'time (sec)';
            ylabel 'amplitude';
            grid on
            subplot(2,2,2);
            plot(t2,app.res2);
            xlabel 'time(sec)'
            ylabel 'amplitude';
            grid on;
            
            subplot(2,2,3);
            plot(F1, toPlot1);
            xlabel 'Frequency (Hz)';
            ylabel(ylbl);
            grid 'on';
            
            subplot(2,2,4);
            plot(F2, toPlot2);
            xlabel 'Frequency (Hz)';
            ylabel(ylbl);
            grid 'on';


        end

        % Button pushed function: PlotspanButton
        function PlotspanButtonPushed(app, event)
            sF = app.StartfrequencyHzEditField.Value;
            eF = app.EndFrequencyHzEditField.Value;
            sFm = app.Nfft*sF/app.Fs;
            eFm = app.Nfft*eF/app.Fs;
            sig = app.inFreq(length(app.inFreq)/2 + sFm : length(app.inFreq)/2 + eFm);
            F = sF:app.Fs/app.Nfft:eF;
            disp(length(F));
            disp(length(sig));
            %%
            
            
            plot(app.AxesFreq_2,F,abs(sig));
            
            %%
            %
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
           
            %%
            nBlock = app.NblockEditField.Value;
            outSig = RMS_Average(app.input, nBlock);
            %%
            F = -length(outSig)/2:1:length(outSig)/2 -1;
            N_Here = 2^nextpow2(length(outSig));
            F = F*app.Fs/N_Here; %f = mFs/N
            outSig = fftshift(outSig);
            disp(length(outSig));
            plot(F,abs(outSig));
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            sF = app.StartfrequencyHzEditField.Value;
            eF = app.EndFrequencyHzEditField.Value;
            sFm = app.Nfft*sF/app.Fs;
            eFm = app.Nfft*eF/app.Fs;
            sig = app.inFreq(length(app.inFreq)/2 + sFm : length(app.inFreq)/2 + eFm);
            F = sF:app.Fs/app.Nfft:eF;
            %%
            [power, maxVal, maxI] = Power_Max(abs(sig));
            app.Label_2.Text = ['Signal power is: ',num2str(power),' max val: ' num2str(maxVal)];
            app.Label_3.Text = ['Max is at Frequency: ', num2str(F(maxI)), 'Hz'];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputButtonGroup = uibuttongroup(app.UIFigure);
            app.InputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputButtonGroupSelectionChanged, true);
            app.InputButtonGroup.Title = 'Input';
            app.audiofileButton = uiradiobutton(app.InputButtonGroup);
            app.audiofileButton.Text = 'audio file';
            app.audiofileButton.Value = true;
            app.BuiltinfuncitonsButton = uiradiobutton(app.InputButtonGroup);
            app.BuiltinfuncitonsButton.Text = 'Built in funcitons';
            app.BrowseButton = uibutton(app.InputButtonGroup, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BuiltDropDown = uidropdown(app.InputButtonGroup);
            app.BuiltDropDown.Items = {'Sinusoid', 'Window'};
            app.BuiltDropDown.ValueChangedFcn = createCallbackFcn(app, @BuiltDropDownValueChanged, true);
            app.BuiltDropDown.Value = 'Sinusoid';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimeplotTab = uitab(app.TabGroup);
            app.TimeplotTab.Title = 'Time plot';
            app.AxesTime = uiaxes(app.TimeplotTab);
            title(app.AxesTime, {'Time plot'; ''})
            xlabel(app.AxesTime, 'Time (sec)')
            ylabel(app.AxesTime, 'Amplitude')
            app.FrequencyplotTab = uitab(app.TabGroup);
            app.FrequencyplotTab.Title = 'Frequency plot';
            app.AxesFreq = uiaxes(app.FrequencyplotTab);
            title(app.AxesFreq, 'Frequency Plot')
            xlabel(app.AxesFreq, 'Frequency (Hz)')
            ylabel(app.AxesFreq, 'Y')
            app.ComparisonTab = uitab(app.TabGroup);
            app.ComparisonTab.Title = 'Comparison';
            app.CompareButton = uibutton(app.ComparisonTab, 'push');
            app.CompareButton.ButtonPushedFcn = createCallbackFcn(app, @CompareButtonPushed, true);
            app.CompareButton.Text = 'Compare';
            app.Window1Panel = uipanel(app.ComparisonTab);
            app.Window1Panel.Title = 'Window 1';
            app.Window1TypeDropDownLabel = uilabel(app.Window1Panel);
            app.Window1TypeDropDownLabel.Text = 'Window1 Type';
            app.Window1TypeDropDown = uidropdown(app.Window1Panel);
            app.Window1TypeDropDown.Items = {'Rectangular', 'Triangular', 'Hanning', 'Hamming'};
            app.Window1TypeDropDown.Value = 'Rectangular';
            app.Window1lengthEditFieldLabel = uilabel(app.Window1Panel);
            app.Window1lengthEditFieldLabel.Text = 'Window1 length';
            app.Window1lengthEditField = uieditfield(app.Window1Panel, 'numeric');
            app.NfftDropDown_2Label = uilabel(app.Window1Panel);
            app.NfftDropDown_2Label.Text = 'N-fft';
            app.NfftDropDown_2 = uidropdown(app.Window1Panel);
            app.NfftDropDown_2.Items = {};
            app.NfftDropDown_2.Value = {};
            app.Window2Panel = uipanel(app.ComparisonTab);
            app.Window2Panel.Title = 'Window 2';
            app.Window2TypeDropDownLabel = uilabel(app.Window2Panel);
            app.Window2TypeDropDownLabel.Text = 'Window2 Type';
            app.Window2TypeDropDown = uidropdown(app.Window2Panel);
            app.Window2TypeDropDown.Items = {'Rectangular', 'Triangular', 'Hanning', 'Hamming'};
            app.Window2TypeDropDown.Value = 'Rectangular';
            app.Window2lengthEditFieldLabel = uilabel(app.Window2Panel);
            app.Window2lengthEditFieldLabel.Text = 'Window2 length';
            app.Window2lengthEditField = uieditfield(app.Window2Panel, 'numeric');
            app.NfftDropDown_3Label = uilabel(app.Window2Panel);
            app.NfftDropDown_3Label.Text = 'N-fft';
            app.NfftDropDown_3 = uidropdown(app.Window2Panel);
            app.NfftDropDown_3.Items = {};
            app.NfftDropDown_3.Value = {};
            app.CalculationTab = uitab(app.TabGroup);
            app.CalculationTab.Title = 'Calculation';
            app.AxesFreq_2 = uiaxes(app.CalculationTab);
            title(app.AxesFreq_2, 'Frequency Plot')
            xlabel(app.AxesFreq_2, 'Frequency (Hz)')
            ylabel(app.AxesFreq_2, 'Y')
            app.EndFrequencyHzEditFieldLabel = uilabel(app.CalculationTab);
            app.EndFrequencyHzEditFieldLabel.Text = 'End Frequency (Hz)';
            app.EndFrequencyHzEditField = uieditfield(app.CalculationTab, 'numeric');
            app.StartfrequencyHzEditFieldLabel = uilabel(app.CalculationTab);
            app.StartfrequencyHzEditFieldLabel.Text = 'Start frequency (Hz)';
            app.StartfrequencyHzEditField = uieditfield(app.CalculationTab, 'numeric');
            app.PlotspanButton = uibutton(app.CalculationTab, 'push');
            app.PlotspanButton.ButtonPushedFcn = createCallbackFcn(app, @PlotspanButtonPushed, true);
            app.PlotspanButton.Text = 'Plot span';
            app.RMSAveragingPanel = uipanel(app.CalculationTab);
            app.RMSAveragingPanel.Title = 'RMS Averaging';
            app.PlotButton_2 = uibutton(app.RMSAveragingPanel, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.NblockEditFieldLabel = uilabel(app.RMSAveragingPanel);
            app.NblockEditFieldLabel.Text = 'N-block';
            app.NblockEditField = uieditfield(app.RMSAveragingPanel, 'numeric');
            app.MaxPowerPanel = uipanel(app.CalculationTab);
            app.MaxPowerPanel.Title = 'Max Power';
            app.CalculateButton = uibutton(app.MaxPowerPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label_2 = uilabel(app.MaxPowerPanel);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.MaxPowerPanel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.CalculationTab);
            app.Label_4.Text = '';
            app.DisplayoptionsTab = uitab(app.TabGroup);
            app.DisplayoptionsTab.Title = 'Display options';
            app.FrequecyplottypeButtonGroup = uibuttongroup(app.DisplayoptionsTab);
            app.FrequecyplottypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FrequecyplottypeButtonGroupSelectionChanged, true);
            app.FrequecyplottypeButtonGroup.Title = 'Frequecy plot type';
            app.MagnitudeButton = uiradiobutton(app.FrequecyplottypeButtonGroup);
            app.MagnitudeButton.Text = 'Magnitude';
            app.MagnitudeButton.Value = true;
            app.PhaseButton = uiradiobutton(app.FrequecyplottypeButtonGroup);
            app.PhaseButton.Text = 'Phase';
            app.RealButton_2 = uiradiobutton(app.FrequecyplottypeButtonGroup);
            app.RealButton_2.Text = 'Real';
            app.ImaginaryButton_2 = uiradiobutton(app.FrequecyplottypeButtonGroup);
            app.ImaginaryButton_2.Text = 'Imaginary';
            app.ScaleButtonGroup = uibuttongroup(app.DisplayoptionsTab);
            app.ScaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScaleButtonGroupSelectionChanged, true);
            app.ScaleButtonGroup.Title = 'Scale';
            app.LinearButton = uiradiobutton(app.ScaleButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.LogarithmicButton = uiradiobutton(app.ScaleButtonGroup);
            app.LogarithmicButton.Text = 'Logarithmic';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.NfftLabel = uilabel(app.UIFigure);
            app.NfftLabel.Text = 'N-fft';
            app.NfftDropDown = uidropdown(app.UIFigure);
            app.NfftDropDown.Items = {};
            app.NfftDropDown.ValueChangedFcn = createCallbackFcn(app, @NfftDropDownValueChanged, true);
            app.NfftDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analyzer01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end