classdef convRes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, myApp)
            shift = myApp.x1(length(myApp.x1)) + myApp.sampRate;
            newX = [myApp.x1, myApp.x2+shift];
            newX = newX(1:length(newX)-1);                %shift the second array
            newY1 = conv(myApp.y1, myApp.y2);
            newY2 = conv(myApp.y2, myApp.y1);
            stem(app.UIAxes, newX, newY1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = convRes(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end